classdef NInterpoulatinGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        QueriesEditFieldLabel     matlab.ui.control.Label
        QueriesEditField          matlab.ui.control.NumericEditField
        InsertButton              matlab.ui.control.Button
        EnterPointButton          matlab.ui.control.Button
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        UITable                   matlab.ui.control.Table
        SolveButton               matlab.ui.control.Button
        NewButton                 matlab.ui.control.Button
        OrderEditFieldLabel       matlab.ui.control.Label
        OrderEditField            matlab.ui.control.NumericEditField
        polynomialEditFieldLabel  matlab.ui.control.Label
        polynomialEditField       matlab.ui.control.EditField
        SimplifiedEditFieldLabel  matlab.ui.control.Label
        SimplifiedEditField       matlab.ui.control.EditField
        FinishButton              matlab.ui.control.Button
        UITable2                  matlab.ui.control.Table
    end


    properties (Access = public)
        Property % Description
        xPoint = 0;
        yPoint = 0;
        matrix;
        Iteration = 1;
        order;
        function1;
        fun2;
        QueriesItr = 1;
        Queries = zeros(50);
        query_result = zeros(50);
    end

    methods (Access = public)
    
        function [final,final_Simplified_func] = NInterpolation(app)
            p = app.matrix(:,1);
            y = app.matrix(:,2);
            if (app.order > app.Iteration - 1)
                 prompt = {'ERROR!!! TOO FEW POINTS TO ACHIEVE THE ORDER'};
                 title = 'Error';
                 dims = [1 35];
                 errordlg(prompt,title,dims);
            end 
            b = zeros(1,length(p));
            temp = zeros(1,length(p));
             for i= 1:1:(app.order+1)
                 if(i == 1)
                     temp(i) = y(i);
                     b(i) = y(i);
                 else
                     temp(i) = (y(i)-y(i-1))/(p(i)- p(i-1));
                     b(i)= temp(i);
                 end
             end
            if (app.order > 1)
                k = 2;
               for i =3:1:length(b)
                   for j= i:1:length(b)
                       b(j) = (temp(j)-temp(j-1))/(p(j)- p(j-k));
                   end
                   temp = b;
                   k = k+1;
               end
            end
            disp(b);
            syms term0(x)
            syms final(x)
            syms lastTerm(x)
            syms final_Simplified_func(x)
            final(x) = zeros(1,length(p));
            lastTerm(x) = zeros(1,length(p));
            term0(x) = zeros(1,2);
            for i= 1:1:length(p)
                if(i == 1)
                    term0(x) = b(i);
                    final(x) = term0(x);
                elseif(i==2)
                        lastTerm(x) = (b(i)*(x-p(i-1)));
                        final(x) = final(x)+lastTerm(x);
                else
                    lastTerm(x) = (lastTerm(x)*b(i)*((x-p(i-1))))/b(i-1);
                    final(x) = final(x)+lastTerm(x);
                end
            end
            final_Simplified_func = simplify(final(x));
    
            fplot(app.UIAxes,final_Simplified_func,[-3,app.xPoint]);
            hold (app.UIAxes,'on')
             scatter(app.UIAxes,p,y,'r');
            legend(app.UIAxes,'Function','Point');
            end
        
    end


    methods (Access = private)

        % Button pushed function: EnterPointButton
        function EnterPointButtonPushed(app, event)
            temp = [app.xPoint,app.yPoint];
            app.UITable.Data(app.Iteration,:) = temp;
            app.Iteration = app.Iteration + 1;
            
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            app.EnterPointButton.Enable = 'on';
            app.matrix = [];
            app.UITable.Data = app.matrix;
            app.Iteration = 1;
            app.UITable2.Visible = 'off';
            hold (app.UIAxes,'off')
              app.FinishButton.Visible = 'off';
             app.InsertButton.Visible = 'off';
        end

        % Value changed function: OrderEditField
        function OrderEditFieldValueChanged(app, event)
            app.order = app.OrderEditField.Value;
            
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            if (length(app.UITable.Data) < 1) 
               prompt = {'ENTER THE POINT FIRST'};
               title = 'Error';
               dims = [1 35];
               errordlg(prompt,title,dims); 
               else 
            app.matrix = sortrows(app.UITable.Data,1);   
            app.xPoint = app.matrix(app.Iteration-1,1);
            disp(app.xPoint);
            app.EnterPointButton.Enable = 'off';
            disp(app.matrix);
            [app.function1,app.fun2] = app.NInterpolation;
             disp(app.function1);
             disp(app.fun2);
             app.polynomialEditField.Value = char(app.function1);
             app.SimplifiedEditField.Value = char(app.fun2);
             app.FinishButton.Visible = 'on';
             app.InsertButton.Visible = 'on';

             end 
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.xPoint = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.yPoint = app.YEditField.Value;            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            for i = 1:1:app.QueriesItr
              app.UITable2.Data(i,1) = app.Queries(i);
              app.UITable2.Data(i,2) = app.query_result(i);
            end 
             hold (app.UIAxes,'on')
             scatter(app.UIAxes,app.UITable2.Data(:,1), app.UITable2.Data(:,2),'g');
              legend(app.UIAxes,'Function','Points','Quiries');
              app.UITable2.Visible = 'on';
              app.QueriesItr = 1; 
              hold (app.UIAxes,'off')
        end

        % Button pushed function: InsertButton
        function InsertButtonPushed(app, event)
            value = app.QueriesEditField.Value;
            if (value > app.matrix(1,1) && value < app.matrix(app.Iteration-1,1))   
                app.Queries(app.QueriesItr) = value;
                app.query_result(app.QueriesItr) = feval(app.function1,value);
                app.QueriesItr = app.QueriesItr + 1;
            else 
               prompt = {'ERROR!!! QUIERY OUT OF  THE INTERVAL'};
               title = 'Error';
               dims = [1 35];
               errordlg(prompt,title,dims);  
            end 
   
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.QueriesEditFieldLabel = uilabel(app.UIFigure);
            app.QueriesEditFieldLabel.Text = 'Queries ';
            app.QueriesEditField = uieditfield(app.UIFigure, 'numeric');
            app.InsertButton = uibutton(app.UIFigure, 'push');
            app.InsertButton.ButtonPushedFcn = createCallbackFcn(app, @InsertButtonPushed, true);
            app.InsertButton.Visible = 'off';
            app.InsertButton.Text = 'Insert';
            app.EnterPointButton = uibutton(app.UIFigure, 'push');
            app.EnterPointButton.ButtonPushedFcn = createCallbackFcn(app, @EnterPointButtonPushed, true);
            app.EnterPointButton.Text = 'Enter Point ';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X''s'; 'Y''s'};
            app.UITable.RowName = {};
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New ';
            app.OrderEditFieldLabel = uilabel(app.UIFigure);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderEditField.ValueChangedFcn = createCallbackFcn(app, @OrderEditFieldValueChanged, true);
            app.polynomialEditFieldLabel = uilabel(app.UIFigure);
            app.polynomialEditFieldLabel.Text = 'polynomial';
            app.polynomialEditField = uieditfield(app.UIFigure, 'text');
            app.polynomialEditField.Editable = 'off';
            app.SimplifiedEditFieldLabel = uilabel(app.UIFigure);
            app.SimplifiedEditFieldLabel.Text = 'Simplified';
            app.SimplifiedEditField = uieditfield(app.UIFigure, 'text');
            app.SimplifiedEditField.Editable = 'off';
            app.FinishButton = uibutton(app.UIFigure, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Visible = 'off';
            app.FinishButton.Text = 'Finish ';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Quieries'; 'Value'};
            app.UITable2.RowName = {};
            app.UITable2.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NInterpoulatinGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end