classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FastModeButton                matlab.ui.control.Button
        EpsilonEditFieldLabel         matlab.ui.control.Label
        EpsilonEditField              matlab.ui.control.NumericEditField
        MaxIterationsEditFieldLabel   matlab.ui.control.Label
        MaxIterationsEditField        matlab.ui.control.NumericEditField
        UITable                       matlab.ui.control.Table
        FunctionEditFieldLabel        matlab.ui.control.Label
        FunctionEditField             matlab.ui.control.EditField
        IntialPointEditFieldLabel     matlab.ui.control.Label
        IntialPointEditField          matlab.ui.control.NumericEditField
        RootEditFieldLabel            matlab.ui.control.Label
        RootEditField                 matlab.ui.control.NumericEditField
        UIAxes_2                      matlab.ui.control.UIAxes
        StepByStepButton              matlab.ui.control.Button
        NextButton                    matlab.ui.control.Button
        SolveButton                   matlab.ui.control.Button
        ExecutionTimeEditFieldLabel   matlab.ui.control.Label
        ExecutionTimeEditField        matlab.ui.control.NumericEditField
        FinalPrecisionEditFieldLabel  matlab.ui.control.Label
        FinalPrecisionEditField       matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        functionValue % Description
        intialPointValue = 0;
        maxItrValue = 50;
        epsilonValue = .00001;
        root;
        flag = 0;
        slowmode = 1;
        finalItr;
        result;
        pp;
        plottangnt;
        plothori;
        markpoint;
    end

    methods (Access = public )
    
        function matrix_reslut = Newton(app)
            format long;
            delete (app.pp);
            delete (app.plottangnt);
            delete (app.plothori);
            delete (app.markpoint);
            y = string (app.functionValue);
            eq_derivative = @(x) diff(sym(char(y)));
            disp((app.maxItrValue+1));
            matrix = zeros(app.maxItrValue+1,6);
            matrix_reslut = zeros(app.maxItrValue+1,6);
            num_of_iterations = 1;
            absolute_error = 1000;
            app.root = 0;
            y2 = diff(sym(char(y)));
            xi =  app.intialPointValue; 
            hold (app.UIAxes_2 , 'on')
            app.UIAxes_2.XAxisLocation = 'origin';
            app.UIAxes_2.YAxisLocation = 'origin';
            app.pp = fplot(app.UIAxes_2,[diff(sym(char(y))),sym(char(y))],[-50,50], 'LineWidth', 2, 'MarkerSize', 4);
            zoom(app.UIAxes_2, 'on');
            legend(app.UIAxes_2,'Drivative','Function','Next Point', 'tangent');
            try 
            tic,
            while(num_of_iterations < app.maxItrValue && abs(absolute_error) > app.epsilonValue)
            newfunctionValue = eval(subs(sym(char(y)),xi));
            x=xi;
            derivativeValue = eval(eq_derivative(1));
            if (derivativeValue == 0)
                 prompt = {'ERROR!!! CAN NOT Divide by ZERO'};
                 title = 'Error';
                 dims = [1 35];
                  errordlg(prompt,title,dims);
                  break;
             end
             xnew = xi - ((newfunctionValue) / (derivativeValue));
             absolute_error = xnew - xi;
             matrix(num_of_iterations,1) =  num_of_iterations;
             matrix(num_of_iterations,2) =  xi;
             matrix(num_of_iterations,3) = newfunctionValue; 
             matrix(num_of_iterations,4) = derivativeValue;
             matrix(num_of_iterations,5) =  xnew;
             matrix(num_of_iterations,6) =  abs(absolute_error);
             num_of_iterations = num_of_iterations + 1;
             xi = xnew; 
             
           end
           if (num_of_iterations == app.maxItrValue  && abs(absolute_error) > app.epsilonValue)
                errordlg('Couldnot find the exact root... method may diverged','Method Error');
           end
              app.finalItr = num_of_iterations;
              num_of_iterations = num_of_iterations - 1;
           if(num_of_iterations > 0) 
                absolute_error = matrix(num_of_iterations,6);
                app.FinalPrecisionEditField.Value = absolute_error;
                app.root = matrix(num_of_iterations,5);
                matrix_reslut = zeros(num_of_iterations,5);
               
           end 
           for i = 1 : num_of_iterations
                  for j = 1 : 6
                  matrix_reslut(i,j) = matrix (i,j);
                  end
           end
           app.ExecutionTimeEditField.Value = toc;
           app.plottangnt = plot(app.UIAxes_2,[matrix(1,2),matrix(1,5)],[matrix(1,3),0],'k');
           app.plothori = plot(app.UIAxes_2,[matrix(1,5),matrix(1,5)],get(app.UIAxes_2,'YLim'),'g');
           app.markpoint = plot(app.UIAxes_2,matrix(1,2),matrix(1,3) , 'r*', 'LineWidth', 2, 'MarkerSize', 5);
           legend(app.UIAxes_2,'Drivative','Function', 'tangent','Next Point');
            catch 
                 prompt = {'ERROR!!! CAN NOT Divide by ZERO'};
                 title = 'Error';
                 dims = [1 35];
                  errordlg(prompt,title,dims);
            end 
             
        end
        
        function solve(app,fun, mode)
            app.FunctionEditField.Value = fun;
            app.functionValue = fun;
            switch mode 
                case 'single'
                    app.StepByStepButtonPushed();
                case 'fast'
                    app.ClaculateButtonPushed();
                otherwise              
            end 
        end
        
    end

    methods (Access = public)
    
        function  readFile(app,func,interval,tolerance,maxIter)
            app.FunctionEditField.Value = func;
            app.EpsilonEditField.Value = tolerance;
            x = (interval(1) + interval(2))/2;
            app.IntialPointEditField.Value = x;
            app.MaxIterationsEditField.Value = maxIter;
             app.functionValue = func;
            app.intialPointValue = x;
            app.maxItrValue = maxIter;
            app.epsilonValue = tolerance;
            
        end
        
    end


    methods (Access = private)

        % Callback function: FastModeButton, UITable
        function ClaculateButtonPushed(app, event)
            app.flag = 1;
            app.NextButton.Visible = 'off';
            app.StepByStepButton.Visible = 'off';
            app.UITable.Data = app.Newton;  
            app.RootEditField.Value = app.root;
            app.flag = 0;
        end

        % Value changed function: EpsilonEditField
        function EpsilonEditFieldValueChanged(app, event)
            app.epsilonValue = app.EpsilonEditField.Value;        
        end

        % Value changing function: FunctionEditField
        function FunctionEditFieldValueChanging(app, event)
            app.functionValue = event.Value;      
        end

        % Value changed function: IntialPointEditField
        function IntialPointEditFieldValueChanged(app, event)
            app.intialPointValue = app.IntialPointEditField.Value;          
        end

        % Value changed function: MaxIterationsEditField
        function MaxIterationsEditFieldValueChanged(app, event)
            app.maxItrValue = app.MaxIterationsEditField.Value;  
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            delete (app.plottangnt);
            delete (app.plothori);
            delete (app.markpoint);
            if (app.slowmode < app.finalItr)
                  n = [app.slowmode];
                  rgbs = app.result(app.slowmode,:);
                  app.UITable.Data (app.slowmode,:)= rgbs;
                  app.plottangnt = plot(app.UIAxes_2,[ app.UITable.Data(app.slowmode,2), app.UITable.Data(app.slowmode,5)],[ app.UITable.Data(app.slowmode,3),0],'k');
                  app.plothori = plot(app.UIAxes_2,[ app.UITable.Data(app.slowmode,5), app.UITable.Data(app.slowmode,5)],get(app.UIAxes_2,'YLim'),'g');
                  app.markpoint = plot(app.UIAxes_2, app.UITable.Data(app.slowmode,2),app.UITable.Data(app.slowmode,3) , 'r*', 'LineWidth', 2, 'MarkerSize', 5);
                  legend(app.UIAxes_2,'Drivative','Function','tangent','Next Point');
                  app.slowmode = app.slowmode + 1;
              elseif (app.slowmode == app.finalItr)
                      errordlg('Finish Slow Mode','Alarm');
                      app.RootEditField.Value = app.root;
                      app.flag = 0;
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            if (~app.flag)
            app.FastModeButton.Visible = 'on';
            app.StepByStepButton.Visible = 'on';
            app.NextButton.Visible = 'off';
            matrix = [];
            app.UITable.Data = matrix;
            app.slowmode = 1;
            end
        end

        % Button pushed function: StepByStepButton
        function StepByStepButtonPushed(app, event)
            app.flag = 1;           
            app.FastModeButton.Visible = 'off';     
            app.NextButton.Visible = 'on';
            app.result = app.Newton;  
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FastModeButton = uibutton(app.UIFigure, 'push');
            app.FastModeButton.ButtonPushedFcn = createCallbackFcn(app, @ClaculateButtonPushed, true);
            app.FastModeButton.Visible = 'off';
            app.FastModeButton.Text = 'Fast Mode';
            app.EpsilonEditFieldLabel = uilabel(app.UIFigure);
            app.EpsilonEditFieldLabel.Text = ' Epsilon';
            app.EpsilonEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpsilonEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonEditFieldValueChanged, true);
            app.EpsilonEditField.Limits = [0 Inf];
            app.EpsilonEditField.Value = 0.0001;
            app.MaxIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxIterationsEditFieldLabel.Text = 'Max Iterations ';
            app.MaxIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIterationsEditFieldValueChanged, true);
            app.MaxIterationsEditField.Limits = [0 Inf];
            app.MaxIterationsEditField.Value = 50;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'# Iterations '; 'Xi'; 'f(Xi)'; 'f''(Xi)'; 'Xi+1'; '|Error|'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @ClaculateButtonPushed, true);
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditField.ValueChangingFcn = createCallbackFcn(app, @FunctionEditFieldValueChanging, true);
            app.FunctionEditField.Editable = 'off';
            app.IntialPointEditFieldLabel = uilabel(app.UIFigure);
            app.IntialPointEditFieldLabel.Text = 'Intial Point';
            app.IntialPointEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntialPointEditField.ValueChangedFcn = createCallbackFcn(app, @IntialPointEditFieldValueChanged, true);
            app.RootEditFieldLabel = uilabel(app.UIFigure);
            app.RootEditFieldLabel.Text = 'Root';
            app.RootEditField = uieditfield(app.UIFigure, 'numeric');
            app.RootEditField.ValueDisplayFormat = '%11.10g';
            app.RootEditField.Editable = 'off';
            app.RootEditField.Value = -Inf;
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Function ')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XLim = [0 1];
            app.UIAxes_2.XLimMode = 'manual';
            app.UIAxes_2.YLim = [0 1];
            app.UIAxes_2.YLimMode = 'manual';
            app.StepByStepButton = uibutton(app.UIFigure, 'push');
            app.StepByStepButton.ButtonPushedFcn = createCallbackFcn(app, @StepByStepButtonPushed, true);
            app.StepByStepButton.Visible = 'off';
            app.StepByStepButton.Text = 'Step By Step ';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Visible = 'off';
            app.NextButton.Text = 'Next ';
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.ExecutionTimeEditFieldLabel = uilabel(app.UIFigure);
            app.ExecutionTimeEditFieldLabel.Text = 'Execution Time';
            app.ExecutionTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExecutionTimeEditField.ValueDisplayFormat = '%11.9g';
            app.ExecutionTimeEditField.Editable = 'off';
            app.FinalPrecisionEditFieldLabel = uilabel(app.UIFigure);
            app.FinalPrecisionEditFieldLabel.Text = 'Final Precision  ';
            app.FinalPrecisionEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalPrecisionEditField.ValueDisplayFormat = '%11.10g';
            app.FinalPrecisionEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end